classdef CameraObserver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        SavepictureButton            matlab.ui.control.Button
        ConnecttocameraButton        matlab.ui.control.Button
        StatusLine                   matlab.ui.control.Label
        DiscconectButton             matlab.ui.control.Button
        NameofpictureEditFieldLabel  matlab.ui.control.Label
        NameofpictureEditField       matlab.ui.control.EditField
        StartstreamButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Path char; % Description
        CamObj; %camera object
        Filename char;
        ImgName char;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path)
            app.Path=path;
            app.UIFigure.Name='My Custom Preview Window';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.CamObj);
            delete(app);            
        end

        % Button pushed function: ConnecttocameraButton
        function ConnecttocameraButtonPushed(app, event)
            list=gigecamlist;
            IP=char(char(list.IPAddress(1)));
            g = gigecam(IP);
            %executeCommand(g, 'ColorTransformationResetToFactoryList')
            g.ColorTransformationAuto='continuous';
            g.AcquisitionFrameRateEnable = 'True';
            g.AcquisitionFrameRate = 1;
            g.ExposureTime = 50e+3;
            g.PixelFormat='BGR8';
            %g.TriggerMode='off';
            
            app.CamObj=g;
            
            app.StatusLine.Text=['Connected to: ' char(app.CamObj.DeviceModelName)];
        end

        % Button pushed function: StartstreamButton
        function StartstreamButtonPushed(app, event)
            %figure('Name', 'My Custom Preview Window'); 

            h=app.CamObj.AutoFeatureHeight;
            w=app.CamObj.AutoFeatureWidth;
            
            hImage = image(app.UIAxes,zeros(h , w, 3)); 
            pause(5);
            
            
            %axis on;
            
            
            %axis image;
            
            if w>h
                r=double(h)/double(w);
            else
                r=double(w)/double(h);
            end
            %ax=axis;
            %fig=gcf;
            app.UIAxes.Xlim=[0 w];
            app.UIAxes.Ylim=[0 h];
            pbaspect(app.UIAxes,[1 r 1]);
            preview(app.CamObj,hImage);
            %app.UIAxes.Layer ='top';
            %app.UIAxes='on';
            %app.UIAxes.Grid
            %grid on;
            %set(fig,'position',[417 272 1086 701]);
        end

        % Button pushed function: DiscconectButton
        function DiscconectButtonPushed(app, event)
            delete(app.CamObj);         
            cla( app.UIAxes ,'reset') ;
            app.StatusLine.Text='Disconnected from camera!';
            app.NameofpictureEditField.Value='';
        end

        % Value changed function: NameofpictureEditField
        function NameofpictureEditFieldValueChanged(app, event)
            app.ImgName = char(app.NameofpictureEditField.Value);
            app.Filename=[app.Path app.ImgName '.jpg'];
        end

        % Button pushed function: SavepictureButton
        function SavepictureButtonPushed(app, event)
            img = snapshot(app.CamObj);
            if ~isfile(app.Filename)
                imwrite(img,app.Filename); 
                app.StatusLine.Text=['Succesfully writen file: ' app.ImgName];                
            else
                app.StatusLine.Text='This filename already exist, choose another one';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SavepictureButton = uibutton(app.GridLayout, 'push');
            app.SavepictureButton.ButtonPushedFcn = createCallbackFcn(app, @SavepictureButtonPushed, true);
            app.SavepictureButton.Text = 'Save picture';
            app.ConnecttocameraButton = uibutton(app.GridLayout, 'push');
            app.ConnecttocameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttocameraButtonPushed, true);
            app.ConnecttocameraButton.Text = 'Connect to camera';
            app.StatusLine = uilabel(app.GridLayout);
            app.DiscconectButton = uibutton(app.GridLayout, 'push');
            app.DiscconectButton.ButtonPushedFcn = createCallbackFcn(app, @DiscconectButtonPushed, true);
            app.DiscconectButton.Text = 'Discconect';
            app.NameofpictureEditFieldLabel = uilabel(app.GridLayout);
            app.NameofpictureEditFieldLabel.Text = 'Name of picture';
            app.NameofpictureEditField = uieditfield(app.GridLayout, 'text');
            app.NameofpictureEditField.ValueChangedFcn = createCallbackFcn(app, @NameofpictureEditFieldValueChanged, true);
            app.StartstreamButton = uibutton(app.GridLayout, 'push');
            app.StartstreamButton.ButtonPushedFcn = createCallbackFcn(app, @StartstreamButtonPushed, true);
            app.StartstreamButton.Text = 'Start stream';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraObserver(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end